classdef gui_pj2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        AkshatGuptaLabel           matlab.ui.control.Label
        SarahScrogginsLabel        matlab.ui.control.Label
        AdityaGowthamanLabel       matlab.ui.control.Label
        SreekanthGandavarapuLabel  matlab.ui.control.Label
        TeamLabel                  matlab.ui.control.Label
        TITLEBALLTHROWINGROBOTICARMLabel  matlab.ui.control.Label
        RestartButton              matlab.ui.control.Button
        MAE547PROJECT2TEAM5Label   matlab.ui.control.Label
        TextArea                   matlab.ui.control.TextArea
        RangeButton                matlab.ui.control.StateButton
        TH3EditField               matlab.ui.control.NumericEditField
        TH3EditFieldLabel          matlab.ui.control.Label
        TH2EditField               matlab.ui.control.NumericEditField
        TH2EditFieldLabel          matlab.ui.control.Label
        TH1EditField               matlab.ui.control.NumericEditField
        TH1EditFieldLabel          matlab.ui.control.Label
        L3EditField                matlab.ui.control.NumericEditField
        L3EditFieldLabel           matlab.ui.control.Label
        L2EditField                matlab.ui.control.NumericEditField
        L2EditFieldLabel           matlab.ui.control.Label
        L1EditField                matlab.ui.control.NumericEditField
        L1EditFieldLabel           matlab.ui.control.Label
        startButton                matlab.ui.control.StateButton
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: L1EditField
        function L1EditFieldValueChanged(app, event)
            L1 = app.L1EditField.Value;
            assignin("base","L1",L1);
            save('inputs.mat','L1','-append')
            
        end

        % Value changed function: L2EditField
        function L2EditFieldValueChanged(app, event)
            L2 = app.L2EditField.Value;
            assignin("base","L2",L2);
            save('inputs.mat','L2','-append')
        end

        % Value changed function: L3EditField
        function L3EditFieldValueChanged(app, event)
            L3 = app.L3EditField.Value;
            assignin("base","L3",L3);
            save('inputs.mat','L3','-append')
        end

        % Value changed function: TH1EditField
        function TH1EditFieldValueChanged(app, event)
            TH1 = app.TH1EditField.Value;
            assignin("base","TH1",TH1);
            save('inputs.mat','TH1','-append')
        end

        % Value changed function: TH2EditField
        function TH2EditFieldValueChanged(app, event)
            TH2 = app.TH2EditField.Value;
            assignin("base","TH2",TH2);
            save('inputs.mat','TH2','-append')
        end

        % Value changed function: TH3EditField
        function TH3EditFieldValueChanged(app, event)
            TH3 = app.TH3EditField.Value;
            assignin("base","TH3",TH3);
            save('inputs.mat','TH3','-append')
        end

        % Value changed function: startButton
        function startButtonValueChanged(app, event)
            run("arm_main.m");
%             range1=evalin('base','range1');
            app.RangeTextArea.Value=sprintf('%f',range1);
        end

        % Value changed function: RangeButton
        function RangeButtonValueChanged(app, event)
            value = app.RangeButton.Value;
            range1=evalin('base','range1');
            app.TextArea.Value=sprintf('%f',range1);
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
              cla;close all;            
            run("gui_pj2.mlapp");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Simulation Results')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [-10 10];
            app.UIAxes.YLim = [-10 10];
            app.UIAxes.ZLim = [-10 10];
            app.startButton = uibutton(app.UIFigure, 'state');
            app.startButton.ValueChangedFcn = createCallbackFcn(app, @startButtonValueChanged, true);
            app.startButton.Text = 'start';
            app.L1EditFieldLabel = uilabel(app.UIFigure);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1EditField.ValueChangedFcn = createCallbackFcn(app, @L1EditFieldValueChanged, true);
            app.L2EditFieldLabel = uilabel(app.UIFigure);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L2EditField.ValueChangedFcn = createCallbackFcn(app, @L2EditFieldValueChanged, true);
            app.L3EditFieldLabel = uilabel(app.UIFigure);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.UIFigure, 'numeric');
            app.L3EditField.ValueChangedFcn = createCallbackFcn(app, @L3EditFieldValueChanged, true);
            app.TH1EditFieldLabel = uilabel(app.UIFigure);
            app.TH1EditFieldLabel.Text = 'TH1';
            app.TH1EditField = uieditfield(app.UIFigure, 'numeric');
            app.TH1EditField.ValueChangedFcn = createCallbackFcn(app, @TH1EditFieldValueChanged, true);
            app.TH2EditFieldLabel = uilabel(app.UIFigure);
            app.TH2EditFieldLabel.Text = 'TH2';
            app.TH2EditField = uieditfield(app.UIFigure, 'numeric');
            app.TH2EditField.ValueChangedFcn = createCallbackFcn(app, @TH2EditFieldValueChanged, true);
            app.TH3EditFieldLabel = uilabel(app.UIFigure);
            app.TH3EditFieldLabel.Text = 'TH3';
            app.TH3EditField = uieditfield(app.UIFigure, 'numeric');
            app.TH3EditField.ValueChangedFcn = createCallbackFcn(app, @TH3EditFieldValueChanged, true);
            app.RangeButton = uibutton(app.UIFigure, 'state');
            app.RangeButton.ValueChangedFcn = createCallbackFcn(app, @RangeButtonValueChanged, true);
            app.RangeButton.Text = 'Range';
            app.TextArea = uitextarea(app.UIFigure);
            app.MAE547PROJECT2TEAM5Label = uilabel(app.UIFigure);
            app.MAE547PROJECT2TEAM5Label.Text = 'MAE 547 PROJECT 2- TEAM 5';
            app.RestartButton = uibutton(app.UIFigure, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.TITLEBALLTHROWINGROBOTICARMLabel = uilabel(app.UIFigure);
            app.TITLEBALLTHROWINGROBOTICARMLabel.Text = 'TITLE : BALL THROWING ROBOTIC ARM ';
            app.TeamLabel = uilabel(app.UIFigure);
            app.TeamLabel.Text = 'Team : ';
            app.SreekanthGandavarapuLabel = uilabel(app.UIFigure);
            app.SreekanthGandavarapuLabel.Text = 'Sreekanth Gandavarapu';
            app.AdityaGowthamanLabel = uilabel(app.UIFigure);
            app.AdityaGowthamanLabel.Text = 'Aditya Gowthaman';
            app.SarahScrogginsLabel = uilabel(app.UIFigure);
            app.SarahScrogginsLabel.Text = 'Sarah Scroggins';
            app.AkshatGuptaLabel = uilabel(app.UIFigure);
            app.AkshatGuptaLabel.Text = 'Akshat Gupta';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_pj2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end